classdef BNPower < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TtestTab                        matlab.ui.container.Tab
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        PowerCIEditField_4Label         matlab.ui.control.Label
        PowerCIEditField_4              matlab.ui.control.EditField
        UseparallelcomputingCheckBox    matlab.ui.control.CheckBox
        MeasurementReliabilityDropDown  matlab.ui.control.DropDown
        MeasurementReliabilityLabel     matlab.ui.control.Label
        StatisticalPowerLabel           matlab.ui.control.Label
        TimeelapsedsEditField           matlab.ui.control.EditField
        TimeelapsedsEditFieldLabel      matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        CovarianceMatrixDropDown        matlab.ui.control.DropDown
        CovarianceMatrixDropDownLabel   matlab.ui.control.Label
        VariationLabel                  matlab.ui.control.Label
        EffectSizeLabel_2               matlab.ui.control.Label
        SampleSizeLabel                 matlab.ui.control.Label
        ShowExampleNetworkButton        matlab.ui.control.Button
        PermutationTestLabel            matlab.ui.control.Label
        GraphStructureLabel             matlab.ui.control.Label
        SampleSizeandEffectSizeLabel    matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        VcEditField                     matlab.ui.control.NumericEditField
        VcLabel                         matlab.ui.control.Label
        Rho1EditField_3                 matlab.ui.control.NumericEditField
        Rho0EditField_3                 matlab.ui.control.NumericEditField
        Rho0EditField_3Label            matlab.ui.control.Label
        Rho1EditField_3Label            matlab.ui.control.Label
        S_BEditField                    matlab.ui.control.NumericEditField
        S_BEditFieldLabel               matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        NEditField                      matlab.ui.control.NumericEditField
        MEditField_3                    matlab.ui.control.NumericEditField
        CohensdEditField                matlab.ui.control.NumericEditField
        alphaEditField                  matlab.ui.control.NumericEditField
        CohensdEditFieldLabel           matlab.ui.control.Label
        SigmaEditFieldLabel             matlab.ui.control.Label
        NEditFieldLabel                 matlab.ui.control.Label
        MEditField_3Label               matlab.ui.control.Label
        alphaEditFieldLabel             matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        S_AEditField                    matlab.ui.control.NumericEditField
        S_AEditFieldLabel               matlab.ui.control.Label
        RegressionTab                   matlab.ui.container.Tab
        MethodDropDown_2                matlab.ui.control.DropDown
        MethodDropDown_2Label           matlab.ui.control.Label
        CovariatesEditField             matlab.ui.control.NumericEditField
        CovariatesEditFieldLabel        matlab.ui.control.Label
        PowerCIEditField_5Label         matlab.ui.control.Label
        PowerCIEditField_5              matlab.ui.control.EditField
        UseparallelcomputingCheckBox_2  matlab.ui.control.CheckBox
        MeasurementReliabilityDropDown_2  matlab.ui.control.DropDown
        MeasurementReliabilityDropDown_2Label  matlab.ui.control.Label
        TimeelapsedsEditField_2         matlab.ui.control.EditField
        TimeelapsedsEditField_2Label    matlab.ui.control.Label
        KEditField_2                    matlab.ui.control.NumericEditField
        KEditField_2Label               matlab.ui.control.Label
        StatisticalPowerLabel_2         matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        CovarianceMatrixDropDown_2      matlab.ui.control.DropDown
        CovarianceMatrixDropDown_2Label  matlab.ui.control.Label
        PermutationTestLabel_2          matlab.ui.control.Label
        VariationLabel_2                matlab.ui.control.Label
        EffectSizeLabel_3               matlab.ui.control.Label
        SampleSizeLabel_2               matlab.ui.control.Label
        SampleSizeandEffectSizeLabel_2  matlab.ui.control.Label
        GraphStructureLabel_2           matlab.ui.control.Label
        ShowExampleNetworkButton_2      matlab.ui.control.Button
        Cohensf2EditField               matlab.ui.control.NumericEditField
        Cohensf2EditFieldLabel          matlab.ui.control.Label
        NEditField_2Label               matlab.ui.control.Label
        NEditField_2                    matlab.ui.control.NumericEditField
        MEditField_4Label               matlab.ui.control.Label
        MEditField_4                    matlab.ui.control.NumericEditField
        alphaEditField_2Label           matlab.ui.control.Label
        alphaEditField_2                matlab.ui.control.NumericEditField
        SigmaEditField_2                matlab.ui.control.NumericEditField
        SigmaEditField_2Label           matlab.ui.control.Label
        SEditField                      matlab.ui.control.NumericEditField
        SEditFieldLabel                 matlab.ui.control.Label
        VcEditField_2                   matlab.ui.control.NumericEditField
        VcLabel_2                       matlab.ui.control.Label
        Rho0EditField_4                 matlab.ui.control.NumericEditField
        Rho0EditField_4Label            matlab.ui.control.Label
        Rho1EditField_4                 matlab.ui.control.NumericEditField
        Rho1EditField_4Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Covariance_matrix = [];
        Reliability_matrix = [];
        stopRequested = false;
        UseParallel = false;
        IsCovarianceDefault = true;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add utils to path
            utilsPath = [pwd, '\utils'];
            addpath(utilsPath);
            
            % Set the text of app.SigmaEditFieldLabel to the Greek letter Sigma (Σ)
            app.SigmaEditFieldLabel.Text = char(963);
            
            % Set the text of app.SigmaEditField_2Label to the Greek letter Sigma (Σ)
            app.SigmaEditField_2Label.Text = char(963);
            
            % Set the text of app.Rho1EditField_3Label to the Greek letter Rho (ρ) followed by '1'
            app.Rho1EditField_3Label.Text = [char(961),'1'];
            
            % Set the text of app.Rho0EditField_3Label to the Greek letter Rho (ρ) followed by '0'
            app.Rho0EditField_3Label.Text = [char(961),'0'];
            
            % Set the text of app.Rho1EditField_4Label to the Greek letter Rho (ρ) followed by '1'
            app.Rho1EditField_4Label.Text = [char(961),'1'];
            
            % Set the text of app.Rho0EditField_4Label to the Greek letter Rho (ρ) followed by '0'
            app.Rho0EditField_4Label.Text = [char(961),'0'];
            
            % Set the text of app.alphaEditFieldLabel to the Greek letter Alpha (α)
            app.alphaEditFieldLabel.Text = char(945);
            
            % Set the text of app.alphaEditField_2Label to the Greek letter Alpha (α)
            app.alphaEditField_2Label.Text = char(945);
            
            % Set the default color of lamps to white
            app.Lamp.Color = 'white';
            
            % Set the default color of lamps to white
            app.Lamp_2.Color = 'white';
            
            % Disable the Editable property of the PowerCIEditField_4 to prevent user input
            app.PowerCIEditField_4.Editable = 'off';
            % Disable the Editable property of the TimeelapsedsEditField to prevent user input
            app.TimeelapsedsEditField.Editable = 'off';
            % Disable the Editable property of the PowerCIEditField_5 to prevent user input
            app.PowerCIEditField_5.Editable = 'off';
            % Disable the Editable property of the TimeelapsedsEditField_2 to prevent user input
            app.TimeelapsedsEditField_2.Editable = 'off';
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            % Retrieve input values from app components
            subject_num = app.SEditField.Value;            % Number of subjects
            sigma0 = app.SigmaEditField_2.Value;          % SD for FC matrices between edges
            N = app.NEditField_2.Value;                  % Size of the entire network
            NE = N*(N-1)/2;                         % Number of edges
            f2 = app.Cohensf2EditField.Value;            % Cohen's f2 for covariate-related edges
            cluster_size = app.VcEditField_2.Value;      % Size of the cluster
            rho_in = app.Rho1EditField_4.Value;          % Proportion of significant edges within the covariate-related subnetwork
            rho_out = app.Rho0EditField_4.Value;         % Proportion of significant edges outside the covariate-related subnetwork
            FWER_threshold = app.alphaEditField_2.Value; % Familywise error rate threshold
            M_rep = app.KEditField_2.Value;              % Number of repetitions for the simulation
            M_perm = app.MEditField_4.Value;             % Number of permutations for the permutation test
            DF = subject_num - 2 - app.CovariatesEditField.Value;                  % Degrees of freedom
            Method = app.MethodDropDown_2.Value;      % Method used for data-driven network analysis
            % Set the Covariance matrix based on the IsCovarianceDefault flag
            if app.IsCovarianceDefault
                app.Covariance_matrix = []; % Set to empty to save memory
            else
                app.Covariance_matrix = app.Covariance_matrix;
            end
            if strcmp(app.MeasurementReliabilityDropDown_2.Value,'Default')
                app.Reliability_matrix = ones(N);
                app.Reliability_matrix = app.Reliability_matrix - diag(diag(app.Reliability_matrix));
            else
                app.Reliability_matrix = app.Reliability_matrix;
            end
            
            
            try
                app.Lamp_2.Color = 'green';
                % Start the stopwatch timer
                tic;
                % Call the 'covariate_net_reg' function with the input parameters and store the result in 'power'
                power = covariate_net_reg(subject_num, N, sigma0, f2, DF, rho_in, rho_out, cluster_size, app.Covariance_matrix, FWER_threshold, M_rep, M_perm, app.Reliability_matrix, app.UseParallel, Method);
                % Calculate the elapsed time
                elapsedTime = toc;
                % Calculate Confidence interval
                CI_lower = power - 1.96*sqrt(power * (1 - power) / M_rep);
                CI_upper = power + 1.96*sqrt(power * (1 - power) / M_rep);
                % Adjust the bounds of the confidence interval
                CI_lower = max(CI_lower, 0);
                CI_upper = min(CI_upper, 1);
                % Format the string with power and confidence interval
                powerCIString = sprintf('%.2f ([%.2f, %.2f])', power, CI_lower, CI_upper);
                % Set the 'Value' property of PowerCIEditField_5 to display
                % the calculated 'power' with confidence interval
                app.PowerCIEditField_5.Value = powerCIString;
                % Display the elapsed time in the app
                app.TimeelapsedsEditField_2.Value = sprintf('%d', floor(elapsedTime));
                % change the color back to white
                app.Lamp_2.Color = 'white';
            catch ME
                % If there's an error, set the Lamp to red
                app.Lamp_2.Color = 'red';
                % Handle any errors:
                if ~isempty(ME.stack)
                    errorMessage = sprintf('Error in function %s at line %d of %s: %s', ...
                        ME.stack(1).name, ME.stack(1).line, ME.stack(1).file, ME.message);
                else
                    errorMessage = sprintf('An error occurred: %s', ME.message);
                end
                errordlg(errorMessage, 'Error');
            end
            
        end

        % Button pushed function: ShowExampleNetworkButton_2
        function ShowExampleNetworkButton_2Pushed(app, event)
            
            % Retrieve input values from app components
            % Total number of nodes
            N = app.NEditField_2.Value;
            NE = N*(N-1)/2;                         % Number of edges
            if app.IsCovarianceDefault
                app.Covariance_matrix = []; % Set to empty to save memory
            else
                app.Covariance_matrix = app.Covariance_matrix;
            end
            if strcmp(app.MeasurementReliabilityDropDown_2.Value,'Default')
                app.Reliability_matrix = ones(N);
                app.Reliability_matrix = app.Reliability_matrix - diag(diag(app.Reliability_matrix));
            else
                app.Reliability_matrix = app.Reliability_matrix;
            end
            
            % Retrieve input values from app components
            subject_num = app.SEditField.Value;            % Number of subjects
            sigma0 = app.SigmaEditField_2.Value;          % SD for FC matrices between edges
            N = app.NEditField_2.Value;                  % Size of the entire network
            f2 = app.Cohensf2EditField.Value;            % Cohen's f2 for covariate-related edges
            cluster_size = app.VcEditField_2.Value;      % Size of the cluster
            rho_in = app.Rho1EditField_4.Value;          % Proportion of significant edges within the covariate-related subnetwork
            rho_out = app.Rho0EditField_4.Value;         % Proportion of significant edges outside the covariate-related subnetwork
            L = chol(app.Covariance_matrix);
            DF = subject_num - 2 - app.CovariatesEditField.Value;                  % Degrees of freedom
            % Call the 'sampling_ind_reg' function to obtain example networks
            [W1, Wp, ~, ~, ~, ~] = sampling_ind_reg(subject_num, N, sigma0, f2, DF, cluster_size, rho_in, rho_out, L, app.Reliability_matrix);
            
            % Display the ground truth network
            figure;
            subplot(1,2,1)
            imagesc(W1);
            hold on
            rectangle('Position',[0.6,0.6,cluster_size, cluster_size], 'EdgeColor', 'r', 'LineWidth', 3);
            colorbar;
            colormap("jet");
            title('Ground Truth','FontSize',24,'FontWeight','bold');
            
            % Display the observed (shuffled) network
            subplot(1,2,2)
            imagesc(Wp);
            colorbar;
            colormap("jet");
            title('Observed (shuffled)','FontSize',24,'FontWeight','bold');
            set(gcf,'Position',[871         918        1191         420]);
            
        end

        % Button pushed function: ShowExampleNetworkButton
        function ShowExampleNetworkButtonPushed(app, event)
            
            % Retrieve input values from app components
            % Total number of nodes
            N = app.NEditField.Value;
            NE = N*(N-1)/2;                         % Number of edges
            if app.IsCovarianceDefault
                app.Covariance_matrix = []; % Set to empty to save memory
            else
                app.Covariance_matrix = app.Covariance_matrix;
            end
            if strcmp(app.MeasurementReliabilityDropDown.Value,'Default')
                app.Reliability_matrix = ones(N);
                app.Reliability_matrix = app.Reliability_matrix - diag(diag(app.Reliability_matrix));
            else
                app.Reliability_matrix = app.Reliability_matrix;
            end
            
            % Retrieve input values from app components
            ctrl_num = app.S_AEditField.Value;          % Number of subjects in the control group
            case_num = app.S_BEditField.Value;          % Number of subjects in the case group
            sigma0 = app.SigmaEditField.Value;          % Standard deviation (sigma)
            Cohensd = app.CohensdEditField.Value;       % Effect size (Cohen's d)
            rho_in = app.Rho1EditField_3.Value;         % Proportion of significant edges within the covariate-related subnetwork
            rho_out = app.Rho0EditField_3.Value;        % Proportion of significant edges outside the covariate-related subnetwork
            cluster_size = app.VcEditField.Value;       % Cluster size
            N = app.NEditField.Value;                   % Total number of subjects
            theta = Cohensd * sigma0;                  % Calculate theta as the product of Cohen's d and sigma
            L = chol(app.Covariance_matrix);
            
            % Call the 'sampling_ind_ttest' function to obtain example networks
            [W1, Wp, ~, ~, ~, ~] = sampling_ind_ttest(ctrl_num, case_num, N, sigma0, theta, cluster_size, rho_in, rho_out, L, app.Reliability_matrix);
            
            % Display the ground truth network
            figure;
            subplot(1,2,1)
            imagesc(W1);
            hold on
            rectangle('Position',[0.6,0.6,cluster_size, cluster_size], 'EdgeColor', 'r', 'LineWidth', 3);
            colorbar;
            colormap("jet");
            title('Ground Truth','FontSize',24,'FontWeight','bold');
            
            % Display the observed (shuffled) network
            subplot(1,2,2)
            imagesc(Wp);
            colorbar;
            colormap("jet");
            title('Observed (shuffled)','FontSize',24,'FontWeight','bold');
            set(gcf,'Position',[871         918        1191         420]);
            
        end

        % Value changed function: CovarianceMatrixDropDown
        function CovarianceMatrixDropDownValueChanged(app, event)
            value = app.CovarianceMatrixDropDown.Value;
            N = app.NEditField.Value;
            NE = N*(N-1)/2;
            % Load covariance matrix if any
            if strcmp(value,'Default')
                app.IsCovarianceDefault = true;
            elseif strcmp(value,'Import')
                [file, path, indx] = uigetfile({'*.mat'; '*.txt'; '*.csv'}, 'Select a file');
                
                % If the user cancels the file selection, set dropdown to 'Default' and return
                if isequal(file, 0) || isequal(path, 0)
                    app.CovarianceMatrixDropDown.Value = 'Default';
                    return;
                end
                
                filename = [path file];
                try
                    app.Covariance_matrix = importdata(filename);
                    app.IsCovarianceDefault = false;
                catch ME
                    % Handle any errors during file import. For instance:
                    errordlg('Failed to import the selected file. Please ensure it has the correct format.', 'File Import Error');
                    app.CovarianceMatrixDropDown.Value = 'Default';
                end
            end
        end

        % Value changed function: CovarianceMatrixDropDown_2
        function CovarianceMatrixDropDown_2ValueChanged(app, event)
            value = app.CovarianceMatrixDropDown_2.Value;
            N = app.NEditField_2.Value;
            NE = N*(N-1)/2;
            % Load covariance matrix if any
            if strcmp(value,'Default')
                app.IsCovarianceDefault = true;
            elseif strcmp(value,'Import')
                [file, path, indx] = uigetfile({'*.mat'; '*.txt'; '*.csv'}, 'Select a file');
                
                % If the user cancels the file selection, set dropdown to 'Default' and return
                if isequal(file, 0) || isequal(path, 0)
                    app.CovarianceMatrixDropDown_2.Value = 'Default';
                    return;
                end
                
                filename = [path file];
                try
                    app.Covariance_matrix = importdata(filename);
                    app.IsCovarianceDefault = false;
                catch ME
                    % Handle any errors during file import. For instance:
                    errordlg('Failed to import the selected file. Please ensure it has the correct format.', 'File Import Error');
                    app.CovarianceMatrixDropDown_2.Value = 'Default';
                end
            end
        end

        % Value changed function: MeasurementReliabilityDropDown
        function MeasurementReliabilityDropDownValueChanged(app, event)
            value = app.MeasurementReliabilityDropDown.Value;
            N = app.NEditField.Value;
            % Load covariance matrix if any
            if strcmp(value,'Default')
                app.Reliability_matrix = ones(N); % Assuming 'N' is defined somewhere in your code
            elseif strcmp(value,'Import')
                [file, path, indx] = uigetfile({'*.mat'; '*.txt'; '*.csv'}, 'Select a file');
                
                % If the user cancels the file selection, set dropdown to 'Default' and return
                if isequal(file, 0) || isequal(path, 0)
                    app.MeasurementReliabilityDropDown.Value = 'Default';
                    return;
                end
                
                filename = [path file];
                try
                    app.Reliability_matrix = importdata(filename);
                catch ME
                    % Handle any errors during file import. For instance:
                    errordlg('Failed to import the selected file. Please ensure it has the correct format.', 'File Import Error');
                    app.MeasurementReliabilityDropDown.Value = 'Default';
                end
            end
        end

        % Value changed function: MeasurementReliabilityDropDown_2
        function MeasurementReliabilityDropDown_2ValueChanged(app, event)
            value = app.MeasurementReliabilityDropDown_2.Value;
            N = app.NEditField_2.Value;
            % Load covariance matrix if any
            if strcmp(value,'Default')
                app.Reliability_matrix = ones(N); % Assuming 'N' is defined somewhere in your code
            elseif strcmp(value,'Import')
                [file, path, indx] = uigetfile({'*.mat'; '*.txt'; '*.csv'}, 'Select a file');
                
                % If the user cancels the file selection, set dropdown to 'Default' and return
                if isequal(file, 0) || isequal(path, 0)
                    app.MeasurementReliabilityDropDown_2.Value = 'Default';
                    return;
                end
                
                filename = [path file];
                try
                    app.Reliability_matrix = importdata(filename);
                catch ME
                    % Handle any errors during file import. For instance:
                    errordlg('Failed to import the selected file. Please ensure it has the correct format.', 'File Import Error');
                    app.MeasurementReliabilityDropDown_2.Value = 'Default';
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Retrieve input values from app components
            % Number of repetitions for the simulation
            M_rep = app.KEditField.Value;
            % Number of permutations for the permutation test
            M_perm = app.MEditField_3.Value;
            % Total number of nodes
            N = app.NEditField.Value;
            NE = N*(N-1)/2;                         % Number of edges
            n1 = app.S_AEditField.Value;            % Number of subjects in group A
            n2 = app.S_BEditField.Value;            % Number of subjects in group B
            sigma0 = app.SigmaEditField.Value;      % Standard deviation (sigma)
            Cohensd = app.CohensdEditField.Value;   % Effect size (Cohen's d)
            rho_in = app.Rho1EditField_3.Value;     % Proportion of significant edges within the covariate-related subnetwork
            rho_out = app.Rho0EditField_3.Value;    % Proportion of significant edges outside the covariate-related subnetwork
            cluster_size = app.VcEditField.Value;   % Cluster size
            FWER_threshold = app.alphaEditField.Value; % Familywise error rate threshold
            Method = app.MethodDropDown.Value;      % Method used for data-driven network analysis
            if app.IsCovarianceDefault
                app.Covariance_matrix = []; % Set to empty to save memory
            else
                app.Covariance_matrix = app.Covariance_matrix;
            end
            
            if strcmp(app.MeasurementReliabilityDropDown.Value,'Default')
                app.Reliability_matrix = ones(N);
                app.Reliability_matrix = app.Reliability_matrix - diag(diag(app.Reliability_matrix));
            else
                app.Reliability_matrix = app.Reliability_matrix;
            end
            
            
            try
                app.Lamp.Color = 'green';
                % Start the stopwatch timer
                tic;
                
                % Call the 'covariate_net_ttest' function with the input parameters and store the result in 'power'
                power = covariate_net_ttest(n1, n2, N, sigma0, Cohensd, rho_in, rho_out, cluster_size, app.Covariance_matrix, FWER_threshold, M_rep, M_perm, app.Reliability_matrix, app.UseParallel, Method);
                % Calculate the elapsed time
                elapsedTime = toc;
                % Calculate Confidence interval
                CI_lower = power - 1.96*sqrt(power * (1 - power) / M_rep);
                CI_upper = power + 1.96*sqrt(power * (1 - power) / M_rep);
                % Adjust the bounds of the confidence interval
                CI_lower = max(CI_lower, 0);
                CI_upper = min(CI_upper, 1);
                % Format the string with power and confidence interval
                powerCIString = sprintf('%.2f ([%.2f, %.2f])', power, CI_lower, CI_upper);
                % Set the 'Value' property of PowerCIEditField_4 to display
                % the calculated 'power' with confidence interval
                app.PowerCIEditField_4.Value = powerCIString;
                % Display the elapsed time in the app
                app.TimeelapsedsEditField.Value = sprintf('%d', floor(elapsedTime));
                % change the color back to white
                app.Lamp.Color = 'white';
            catch ME
                % If there's an error, set the Lamp to red
                app.Lamp.Color = 'red';
                % Handle any errors:
                if ~isempty(ME.stack)
                    errorMessage = sprintf('Error in function %s at line %d of %s: %s', ...
                        ME.stack(1).name, ME.stack(1).line, ME.stack(1).file, ME.message);
                else
                    errorMessage = sprintf('An error occurred: %s', ME.message);
                end
                errordlg(errorMessage, 'Error');
            end
        end

        % Value changed function: UseparallelcomputingCheckBox
        function UseparallelcomputingCheckBoxValueChanged(app, event)
            app.UseParallel = app.UseparallelcomputingCheckBox.Value;
            
        end

        % Value changed function: UseparallelcomputingCheckBox_2
        function UseparallelcomputingCheckBox_2ValueChanged(app, event)
            app.UseParallel = app.UseparallelcomputingCheckBox_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TtestTab = uitab(app.TabGroup);
            app.TtestTab.Title = 'T-test';
            app.S_AEditFieldLabel = uilabel(app.TtestTab);
            app.S_AEditFieldLabel.Text = 'S_A';
            app.S_AEditField = uieditfield(app.TtestTab, 'numeric');
            app.S_AEditField.Value = 75;
            app.SigmaEditField = uieditfield(app.TtestTab, 'numeric');
            app.SigmaEditField.Value = 1;
            app.alphaEditFieldLabel = uilabel(app.TtestTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.MEditField_3Label = uilabel(app.TtestTab);
            app.MEditField_3Label.Text = 'M';
            app.NEditFieldLabel = uilabel(app.TtestTab);
            app.NEditFieldLabel.Text = 'N';
            app.SigmaEditFieldLabel = uilabel(app.TtestTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.CohensdEditFieldLabel = uilabel(app.TtestTab);
            app.CohensdEditFieldLabel.Text = 'Cohen''s d';
            app.alphaEditField = uieditfield(app.TtestTab, 'numeric');
            app.alphaEditField.Value = 0.05;
            app.CohensdEditField = uieditfield(app.TtestTab, 'numeric');
            app.CohensdEditField.Value = 0.6;
            app.MEditField_3 = uieditfield(app.TtestTab, 'numeric');
            app.MEditField_3.Value = 100;
            app.NEditField = uieditfield(app.TtestTab, 'numeric');
            app.NEditField.Value = 100;
            app.RunButton = uibutton(app.TtestTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.S_BEditFieldLabel = uilabel(app.TtestTab);
            app.S_BEditFieldLabel.Text = 'S_B';
            app.S_BEditField = uieditfield(app.TtestTab, 'numeric');
            app.S_BEditField.Value = 75;
            app.Rho1EditField_3Label = uilabel(app.TtestTab);
            app.Rho1EditField_3Label.Text = 'Rho1';
            app.Rho0EditField_3Label = uilabel(app.TtestTab);
            app.Rho0EditField_3Label.Text = 'Rho0';
            app.Rho0EditField_3 = uieditfield(app.TtestTab, 'numeric');
            app.Rho1EditField_3 = uieditfield(app.TtestTab, 'numeric');
            app.Rho1EditField_3.Value = 0.7;
            app.VcLabel = uilabel(app.TtestTab);
            app.VcLabel.Text = '|Vc|';
            app.VcEditField = uieditfield(app.TtestTab, 'numeric');
            app.VcEditField.Value = 30;
            app.KEditFieldLabel = uilabel(app.TtestTab);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.TtestTab, 'numeric');
            app.KEditField.Value = 100;
            app.SampleSizeandEffectSizeLabel = uilabel(app.TtestTab);
            app.SampleSizeandEffectSizeLabel.Text = 'Sample Size and Effect Size';
            app.GraphStructureLabel = uilabel(app.TtestTab);
            app.GraphStructureLabel.Text = 'Graph Structure';
            app.PermutationTestLabel = uilabel(app.TtestTab);
            app.PermutationTestLabel.Text = 'Permutation Test';
            app.ShowExampleNetworkButton = uibutton(app.TtestTab, 'push');
            app.ShowExampleNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ShowExampleNetworkButtonPushed, true);
            app.ShowExampleNetworkButton.Text = 'Show Example Network';
            app.SampleSizeLabel = uilabel(app.TtestTab);
            app.SampleSizeLabel.Text = 'Sample Size';
            app.EffectSizeLabel_2 = uilabel(app.TtestTab);
            app.EffectSizeLabel_2.Text = 'Effect Size';
            app.VariationLabel = uilabel(app.TtestTab);
            app.VariationLabel.Text = 'Variation';
            app.CovarianceMatrixDropDownLabel = uilabel(app.TtestTab);
            app.CovarianceMatrixDropDownLabel.Text = 'Covariance Matrix';
            app.CovarianceMatrixDropDown = uidropdown(app.TtestTab);
            app.CovarianceMatrixDropDown.Items = {'None', 'Import'};
            app.CovarianceMatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @CovarianceMatrixDropDownValueChanged, true);
            app.CovarianceMatrixDropDown.Value = 'None';
            app.Lamp = uilamp(app.TtestTab);
            app.TimeelapsedsEditFieldLabel = uilabel(app.TtestTab);
            app.TimeelapsedsEditFieldLabel.Text = 'Time elapsed (s)';
            app.TimeelapsedsEditField = uieditfield(app.TtestTab, 'text');
            app.StatisticalPowerLabel = uilabel(app.TtestTab);
            app.StatisticalPowerLabel.Text = 'Statistical Power';
            app.MeasurementReliabilityLabel = uilabel(app.TtestTab);
            app.MeasurementReliabilityLabel.Text = 'Measurement Reliability';
            app.MeasurementReliabilityDropDown = uidropdown(app.TtestTab);
            app.MeasurementReliabilityDropDown.Items = {'Default', 'Import'};
            app.MeasurementReliabilityDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasurementReliabilityDropDownValueChanged, true);
            app.MeasurementReliabilityDropDown.Value = 'Default';
            app.UseparallelcomputingCheckBox = uicheckbox(app.TtestTab);
            app.UseparallelcomputingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseparallelcomputingCheckBoxValueChanged, true);
            app.UseparallelcomputingCheckBox.Text = {'Use parallel computing '; '(only if the parallel computing toolbox is installed)'};
            app.PowerCIEditField_4 = uieditfield(app.TtestTab, 'text');
            app.PowerCIEditField_4Label = uilabel(app.TtestTab);
            app.PowerCIEditField_4Label.Text = 'Power (CI)';
            app.MethodDropDownLabel = uilabel(app.TtestTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.TtestTab);
            app.MethodDropDown.Items = {'BNPower', 'NBS'};
            app.MethodDropDown.Value = 'BNPower';
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.Rho1EditField_4Label = uilabel(app.RegressionTab);
            app.Rho1EditField_4Label.Text = 'Rho1';
            app.Rho1EditField_4 = uieditfield(app.RegressionTab, 'numeric');
            app.Rho1EditField_4.Value = 0.7;
            app.Rho0EditField_4Label = uilabel(app.RegressionTab);
            app.Rho0EditField_4Label.Text = 'Rho0';
            app.Rho0EditField_4 = uieditfield(app.RegressionTab, 'numeric');
            app.VcLabel_2 = uilabel(app.RegressionTab);
            app.VcLabel_2.Text = '|Vc|';
            app.VcEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.VcEditField_2.Value = 30;
            app.SEditFieldLabel = uilabel(app.RegressionTab);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.RegressionTab, 'numeric');
            app.SEditField.Value = 100;
            app.SigmaEditField_2Label = uilabel(app.RegressionTab);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.SigmaEditField_2.Value = 1;
            app.alphaEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.alphaEditField_2.Value = 0.05;
            app.alphaEditField_2Label = uilabel(app.RegressionTab);
            app.alphaEditField_2Label.Text = 'alpha';
            app.MEditField_4 = uieditfield(app.RegressionTab, 'numeric');
            app.MEditField_4.Value = 100;
            app.MEditField_4Label = uilabel(app.RegressionTab);
            app.MEditField_4Label.Text = 'M';
            app.NEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.NEditField_2.Value = 100;
            app.NEditField_2Label = uilabel(app.RegressionTab);
            app.NEditField_2Label.Text = 'N';
            app.Cohensf2EditFieldLabel = uilabel(app.RegressionTab);
            app.Cohensf2EditFieldLabel.Text = 'Cohen''s f2';
            app.Cohensf2EditField = uieditfield(app.RegressionTab, 'numeric');
            app.Cohensf2EditField.Value = 0.15;
            app.ShowExampleNetworkButton_2 = uibutton(app.RegressionTab, 'push');
            app.ShowExampleNetworkButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowExampleNetworkButton_2Pushed, true);
            app.ShowExampleNetworkButton_2.Text = 'Show Example Network';
            app.GraphStructureLabel_2 = uilabel(app.RegressionTab);
            app.GraphStructureLabel_2.Text = 'Graph Structure';
            app.SampleSizeandEffectSizeLabel_2 = uilabel(app.RegressionTab);
            app.SampleSizeandEffectSizeLabel_2.Text = 'Sample Size and Effect Size';
            app.SampleSizeLabel_2 = uilabel(app.RegressionTab);
            app.SampleSizeLabel_2.Text = 'Sample Size';
            app.EffectSizeLabel_3 = uilabel(app.RegressionTab);
            app.EffectSizeLabel_3.Text = 'Effect Size';
            app.VariationLabel_2 = uilabel(app.RegressionTab);
            app.VariationLabel_2.Text = 'Variation';
            app.PermutationTestLabel_2 = uilabel(app.RegressionTab);
            app.PermutationTestLabel_2.Text = 'Permutation Test';
            app.CovarianceMatrixDropDown_2Label = uilabel(app.RegressionTab);
            app.CovarianceMatrixDropDown_2Label.Text = 'Covariance Matrix';
            app.CovarianceMatrixDropDown_2 = uidropdown(app.RegressionTab);
            app.CovarianceMatrixDropDown_2.Items = {'None', 'Import'};
            app.CovarianceMatrixDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CovarianceMatrixDropDown_2ValueChanged, true);
            app.CovarianceMatrixDropDown_2.Value = 'None';
            app.Lamp_2 = uilamp(app.RegressionTab);
            app.RunButton_2 = uibutton(app.RegressionTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.StatisticalPowerLabel_2 = uilabel(app.RegressionTab);
            app.StatisticalPowerLabel_2.Text = 'Statistical Power';
            app.KEditField_2Label = uilabel(app.RegressionTab);
            app.KEditField_2Label.Text = 'K';
            app.KEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.KEditField_2.Value = 100;
            app.TimeelapsedsEditField_2Label = uilabel(app.RegressionTab);
            app.TimeelapsedsEditField_2Label.Text = 'Time elapsed (s)';
            app.TimeelapsedsEditField_2 = uieditfield(app.RegressionTab, 'text');
            app.MeasurementReliabilityDropDown_2Label = uilabel(app.RegressionTab);
            app.MeasurementReliabilityDropDown_2Label.Text = 'Measurement Reliability';
            app.MeasurementReliabilityDropDown_2 = uidropdown(app.RegressionTab);
            app.MeasurementReliabilityDropDown_2.Items = {'Default', 'Import'};
            app.MeasurementReliabilityDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MeasurementReliabilityDropDown_2ValueChanged, true);
            app.MeasurementReliabilityDropDown_2.Value = 'Default';
            app.UseparallelcomputingCheckBox_2 = uicheckbox(app.RegressionTab);
            app.UseparallelcomputingCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UseparallelcomputingCheckBox_2ValueChanged, true);
            app.UseparallelcomputingCheckBox_2.Text = {'Use parallel computing '; '(only if the parallel computing toolbox is installed)'};
            app.PowerCIEditField_5 = uieditfield(app.RegressionTab, 'text');
            app.PowerCIEditField_5Label = uilabel(app.RegressionTab);
            app.PowerCIEditField_5Label.Text = 'Power (CI)';
            app.CovariatesEditFieldLabel = uilabel(app.RegressionTab);
            app.CovariatesEditFieldLabel.Text = '# Covariates';
            app.CovariatesEditField = uieditfield(app.RegressionTab, 'numeric');
            app.MethodDropDown_2Label = uilabel(app.RegressionTab);
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodDropDown_2 = uidropdown(app.RegressionTab);
            app.MethodDropDown_2.Items = {'BNPower', 'NBS'};
            app.MethodDropDown_2.Value = 'BNPower';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BNPower

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end