classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TtestTab                       matlab.ui.container.Tab
        ShowExampleNetworkButton       matlab.ui.control.Button
        GraphButton                    matlab.ui.control.Button
        OutputLabel                    matlab.ui.control.Label
        PermutationTestInputLabel      matlab.ui.control.Label
        NetworkInputLabel              matlab.ui.control.Label
        SubjectsInputLabel             matlab.ui.control.Label
        PowerEditField                 matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        RepetitionsEditField           matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel      matlab.ui.control.Label
        ClusterSizeEditField           matlab.ui.control.NumericEditField
        ClusterSizeEditFieldLabel      matlab.ui.control.Label
        Rho0EditField                  matlab.ui.control.NumericEditField
        Rho1EditField                  matlab.ui.control.NumericEditField
        Rho1EditFieldLabel             matlab.ui.control.Label
        Rho0EditFieldLabel             matlab.ui.control.Label
        Sigma1EditField                matlab.ui.control.NumericEditField
        Sigma1EditFieldLabel           matlab.ui.control.Label
        NumofPatientsEditField         matlab.ui.control.NumericEditField
        NumofPatientsEditFieldLabel    matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        NetworkSizeEditField           matlab.ui.control.NumericEditField
        PermutationsEditField          matlab.ui.control.NumericEditField
        ThetaEditField                 matlab.ui.control.NumericEditField
        FWERthresholdEditField         matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        Sigma0EditFieldLabel           matlab.ui.control.Label
        NetworkSizeEditFieldLabel      matlab.ui.control.Label
        PermutationsEditFieldLabel     matlab.ui.control.Label
        FWERthresholdEditFieldLabel    matlab.ui.control.Label
        Sigma0EditField                matlab.ui.control.NumericEditField
        NumofControlsEditField         matlab.ui.control.NumericEditField
        NumofControlsEditFieldLabel    matlab.ui.control.Label
        RegressionTab                  matlab.ui.container.Tab
        ShowExampleNetworkButton_2     matlab.ui.control.Button
        Cohensf2EditField              matlab.ui.control.NumericEditField
        Cohensf2EditFieldLabel         matlab.ui.control.Label
        GraphButton_2                  matlab.ui.control.Button
        NetworkSizeEditField_2Label    matlab.ui.control.Label
        NetworkSizeEditField_2         matlab.ui.control.NumericEditField
        PermutationsEditField_2Label   matlab.ui.control.Label
        PermutationsEditField_2        matlab.ui.control.NumericEditField
        FWERthresholdEditField_2Label  matlab.ui.control.Label
        FWERthresholdEditField_2       matlab.ui.control.NumericEditField
        Sigma0EditField_2              matlab.ui.control.NumericEditField
        Sigma0EditField_2Label         matlab.ui.control.Label
        NumofSubjectsEditField         matlab.ui.control.NumericEditField
        NumofSubjectsEditFieldLabel    matlab.ui.control.Label
        PowerEditField_2               matlab.ui.control.NumericEditField
        PowerEditField_2Label          matlab.ui.control.Label
        RepetitionsEditField_2         matlab.ui.control.NumericEditField
        RepetitionsEditField_2Label    matlab.ui.control.Label
        ClusterSizeEditField_2         matlab.ui.control.NumericEditField
        ClusterSizeEditField_2Label    matlab.ui.control.Label
        Rho1EditField_2                matlab.ui.control.NumericEditField
        Rho1EditField_2Label           matlab.ui.control.Label
        Rho0EditField_2                matlab.ui.control.NumericEditField
        Rho0EditField_2Label           matlab.ui.control.Label
        OutputLabel_2                  matlab.ui.control.Label
        PermutationTestInputLabel_2    matlab.ui.control.Label
        NetworkInputLabel_2            matlab.ui.control.Label
        SubjectsInputLabel_2           matlab.ui.control.Label
        RunButton_2                    matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%             parpool;
            % Number of HC
            n1 = app.NumofControlsEditField.Value;
            n2 = app.NumofPatientsEditField.Value;
            sigma0 = app.Sigma0EditField.Value;
            sigma1 = app.Sigma1EditField.Value;
            theta = app.ThetaEditField.Value;
            rho_in = app.Rho0EditField.Value;
            rho_out = app.Rho1EditField.Value;
            cluster_size = app.ClusterSizeEditField.Value;
            FWER_threshold = app.FWERthresholdEditField.Value;
            % Number of repetitions
            M_rep = app.RepetitionsEditField.Value;
            M_perm = app.PermutationsEditField.Value;
            N = app.NetworkSizeEditField.Value;
            
            [power, ~] = covariate_net_ttest(n1, n2, N, sigma0, sigma1, theta, rho_in, rho_out, cluster_size, FWER_threshold, M_rep, M_perm);
            app.PowerEditField.Value = power;
            
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
%             parpool;
            % Number of subjects
            subject_num = app.NumofSubjectsEditField.Value;
            % SD for the FA matrices between non-covariate-related edges
            sigma0 = app.Sigma0EditField_2.Value;
            % size of the entire network
            N = app.NetworkSizeEditField_2.Value;
            % Cohen's f2 for the covariate-related edges
            f2 = app.Cohensf2EditField.Value;
            % size of the cluster
            cluster_size = app.ClusterSizeEditField_2.Value;
            % Noise
            rho_in = app.Rho0EditField_2.Value;
            rho_out = app.Rho1EditField_2.Value;
            FWER_threshold = app.FWERthresholdEditField_2.Value;
            M_rep = app.RepetitionsEditField_2.Value;
            M_perm = app.PermutationsEditField_2.Value;
            [power, ~] = covariate_net_reg(subject_num, N, sigma0, f2, rho_in, rho_out, cluster_size, FWER_threshold, M_rep, M_perm);
            app.PowerEditField_2.Value = power;
        end

        % Button pushed function: ShowExampleNetworkButton_2
        function ShowExampleNetworkButton_2Pushed(app, event)
            % show an example of the desired network
            % Number of subjects
            subject_num = app.NumofSubjectsEditField.Value;
            % SD for the FA matrices between non-covariate-related edges
            sigma0 = app.Sigma0EditField_2.Value;
            % size of the entire network
            N = app.NetworkSizeEditField_2.Value;
            % Cohen's f2 for the covariate-related edges
            f2 = app.Cohensf2EditField.Value;
            % size of the cluster
            cluster_size = app.ClusterSizeEditField_2.Value;
            % Noise
            rho_in = app.Rho0EditField_2.Value;
            rho_out = app.Rho1EditField_2.Value;
            [W1,Wp,~,~, ~,  ~] = sampling_ind_reg(subject_num, N, sigma0, f2, cluster_size, rho_in, rho_out);
            figure;
            subplot(1,2,1)
            imagesc(W1);colorbar;colormap("jet");
            title('Ground Truth','FontSize',24,'FontWeight','bold')
            subplot(1,2,2)
            imagesc(Wp);colorbar;colormap("jet");
            title('Observed (shuffled)','FontSize',24,'FontWeight','bold')
            set(gcf, 'position',[679         918        1321         420]);

        end

        % Button pushed function: ShowExampleNetworkButton
        function ShowExampleNetworkButtonPushed(app, event)
            % show an example of the desired network
            % Number of HC
            ctrl_num = app.NumofControlsEditField.Value;
            case_num = app.NumofPatientsEditField.Value;
            sigma0 = app.Sigma0EditField.Value;
            sigma1 = app.Sigma1EditField.Value;
            theta = app.ThetaEditField.Value;
            rho_in = app.Rho0EditField.Value;
            rho_out = app.Rho1EditField.Value;
            cluster_size = app.ClusterSizeEditField.Value;
            N = app.NetworkSizeEditField.Value;
            [W1,Wp,~, ~, ~, ~] = sampling_ind_ttest(ctrl_num, case_num, N, sigma0, sigma1, theta, cluster_size, rho_in, rho_out);
            figure;
            subplot(1,2,1)
            imagesc(W1);colorbar;colormap("jet");
            title('Ground Truth','FontSize',24,'FontWeight','bold')
            subplot(1,2,2)
            imagesc(Wp);colorbar;colormap("jet");
            title('Observed (shuffled)','FontSize',24,'FontWeight','bold')
            set(gcf, 'position',[679         918        1321         420]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TtestTab = uitab(app.TabGroup);
            app.TtestTab.Title = 'T-test';
            app.NumofControlsEditFieldLabel = uilabel(app.TtestTab);
            app.NumofControlsEditFieldLabel.Text = 'Num. of Controls';
            app.NumofControlsEditField = uieditfield(app.TtestTab, 'numeric');
            app.NumofControlsEditField.Value = 200;
            app.Sigma0EditField = uieditfield(app.TtestTab, 'numeric');
            app.Sigma0EditField.Value = 1;
            app.FWERthresholdEditFieldLabel = uilabel(app.TtestTab);
            app.FWERthresholdEditFieldLabel.Text = 'FWER threshold';
            app.PermutationsEditFieldLabel = uilabel(app.TtestTab);
            app.PermutationsEditFieldLabel.Text = 'Permutations';
            app.NetworkSizeEditFieldLabel = uilabel(app.TtestTab);
            app.NetworkSizeEditFieldLabel.Text = 'Network Size';
            app.Sigma0EditFieldLabel = uilabel(app.TtestTab);
            app.Sigma0EditFieldLabel.Text = 'Sigma0';
            app.ThetaEditFieldLabel = uilabel(app.TtestTab);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.FWERthresholdEditField = uieditfield(app.TtestTab, 'numeric');
            app.FWERthresholdEditField.Value = 0.05;
            app.ThetaEditField = uieditfield(app.TtestTab, 'numeric');
            app.ThetaEditField.Value = 0.3;
            app.PermutationsEditField = uieditfield(app.TtestTab, 'numeric');
            app.PermutationsEditField.Value = 50;
            app.NetworkSizeEditField = uieditfield(app.TtestTab, 'numeric');
            app.NetworkSizeEditField.Value = 246;
            app.RunButton = uibutton(app.TtestTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumofPatientsEditFieldLabel = uilabel(app.TtestTab);
            app.NumofPatientsEditFieldLabel.Text = 'Num. of Patients';
            app.NumofPatientsEditField = uieditfield(app.TtestTab, 'numeric');
            app.NumofPatientsEditField.Value = 200;
            app.Sigma1EditFieldLabel = uilabel(app.TtestTab);
            app.Sigma1EditFieldLabel.Text = 'Sigma1';
            app.Sigma1EditField = uieditfield(app.TtestTab, 'numeric');
            app.Sigma1EditField.Value = 1;
            app.Rho0EditFieldLabel = uilabel(app.TtestTab);
            app.Rho0EditFieldLabel.Text = 'Rho0';
            app.Rho1EditFieldLabel = uilabel(app.TtestTab);
            app.Rho1EditFieldLabel.Text = 'Rho1';
            app.Rho1EditField = uieditfield(app.TtestTab, 'numeric');
            app.Rho1EditField.Value = 0.03;
            app.Rho0EditField = uieditfield(app.TtestTab, 'numeric');
            app.Rho0EditField.Value = 0.45;
            app.ClusterSizeEditFieldLabel = uilabel(app.TtestTab);
            app.ClusterSizeEditFieldLabel.Text = 'Cluster Size';
            app.ClusterSizeEditField = uieditfield(app.TtestTab, 'numeric');
            app.ClusterSizeEditField.Value = 40;
            app.RepetitionsEditFieldLabel = uilabel(app.TtestTab);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.TtestTab, 'numeric');
            app.RepetitionsEditField.Value = 50;
            app.PowerEditFieldLabel = uilabel(app.TtestTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.TtestTab, 'numeric');
            app.SubjectsInputLabel = uilabel(app.TtestTab);
            app.SubjectsInputLabel.Text = 'Subjects Input';
            app.NetworkInputLabel = uilabel(app.TtestTab);
            app.NetworkInputLabel.Text = 'Network Input';
            app.PermutationTestInputLabel = uilabel(app.TtestTab);
            app.PermutationTestInputLabel.Text = 'Permutation Test Input';
            app.OutputLabel = uilabel(app.TtestTab);
            app.OutputLabel.Text = 'Output';
            app.GraphButton = uibutton(app.TtestTab, 'push');
            app.GraphButton.Text = 'Graph';
            app.ShowExampleNetworkButton = uibutton(app.TtestTab, 'push');
            app.ShowExampleNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ShowExampleNetworkButtonPushed, true);
            app.ShowExampleNetworkButton.Text = 'Show Example Network';
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.RunButton_2 = uibutton(app.RegressionTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.SubjectsInputLabel_2 = uilabel(app.RegressionTab);
            app.SubjectsInputLabel_2.Text = 'Subjects Input';
            app.NetworkInputLabel_2 = uilabel(app.RegressionTab);
            app.NetworkInputLabel_2.Text = 'Network Input';
            app.PermutationTestInputLabel_2 = uilabel(app.RegressionTab);
            app.PermutationTestInputLabel_2.Text = 'Permutation Test Input';
            app.OutputLabel_2 = uilabel(app.RegressionTab);
            app.OutputLabel_2.Text = 'Output';
            app.Rho0EditField_2Label = uilabel(app.RegressionTab);
            app.Rho0EditField_2Label.Text = 'Rho0';
            app.Rho0EditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.Rho0EditField_2.Value = 0.35;
            app.Rho1EditField_2Label = uilabel(app.RegressionTab);
            app.Rho1EditField_2Label.Text = 'Rho1';
            app.Rho1EditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.Rho1EditField_2.Value = 0.03;
            app.ClusterSizeEditField_2Label = uilabel(app.RegressionTab);
            app.ClusterSizeEditField_2Label.Text = 'Cluster Size';
            app.ClusterSizeEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.ClusterSizeEditField_2.Value = 40;
            app.RepetitionsEditField_2Label = uilabel(app.RegressionTab);
            app.RepetitionsEditField_2Label.Text = 'Repetitions';
            app.RepetitionsEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.RepetitionsEditField_2.Value = 50;
            app.PowerEditField_2Label = uilabel(app.RegressionTab);
            app.PowerEditField_2Label.Text = 'Power';
            app.PowerEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.NumofSubjectsEditFieldLabel = uilabel(app.RegressionTab);
            app.NumofSubjectsEditFieldLabel.Text = 'Num. of Subjects';
            app.NumofSubjectsEditField = uieditfield(app.RegressionTab, 'numeric');
            app.NumofSubjectsEditField.Value = 200;
            app.Sigma0EditField_2Label = uilabel(app.RegressionTab);
            app.Sigma0EditField_2Label.Text = 'Sigma0';
            app.Sigma0EditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.Sigma0EditField_2.Value = 1;
            app.FWERthresholdEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.FWERthresholdEditField_2.Value = 0.05;
            app.FWERthresholdEditField_2Label = uilabel(app.RegressionTab);
            app.FWERthresholdEditField_2Label.Text = 'FWER threshold';
            app.PermutationsEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.PermutationsEditField_2.Value = 50;
            app.PermutationsEditField_2Label = uilabel(app.RegressionTab);
            app.PermutationsEditField_2Label.Text = 'Permutations';
            app.NetworkSizeEditField_2 = uieditfield(app.RegressionTab, 'numeric');
            app.NetworkSizeEditField_2.Value = 246;
            app.NetworkSizeEditField_2Label = uilabel(app.RegressionTab);
            app.NetworkSizeEditField_2Label.Text = 'Network Size';
            app.GraphButton_2 = uibutton(app.RegressionTab, 'push');
            app.GraphButton_2.Text = 'Graph';
            app.Cohensf2EditFieldLabel = uilabel(app.RegressionTab);
            app.Cohensf2EditFieldLabel.Text = 'Cohen''s f2';
            app.Cohensf2EditField = uieditfield(app.RegressionTab, 'numeric');
            app.Cohensf2EditField.Value = 0.1;
            app.ShowExampleNetworkButton_2 = uibutton(app.RegressionTab, 'push');
            app.ShowExampleNetworkButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowExampleNetworkButton_2Pushed, true);
            app.ShowExampleNetworkButton_2.Text = 'Show Example Network';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end